classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SubintervalsEditField          matlab.ui.control.EditField
        NhpsonconNEditFieldLabel       matlab.ui.control.Label
        Button                         matlab.ui.control.Button
        InitialConditionEditField      matlab.ui.control.EditField
        Nhpiukinuy0EditFieldLabel      matlab.ui.control.Label
        MethodListBox                  matlab.ui.control.ListBox
        Chn1trong4PPtnhgnngListBoxLabel  matlab.ui.control.Label
        ToleranceEditField             matlab.ui.control.EditField
        NhpsaiseLabel                  matlab.ui.control.Label
        RangeEditField                 matlab.ui.control.EditField
        GiihnucuicamngxEditFieldLabel  matlab.ui.control.Label
        EquationEditField              matlab.ui.control.EditField
        NhpPTviphnEditFieldLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Get input values from UI components
            fxy = app.EquationEditField.Value;
            y0 = str2double(app.InitialConditionEditField.Value);
            x = str2num(app.RangeEditField.Value);  % Assumes range is entered as 'start end'
            N = str2double(app.SubintervalsEditField.Value);
            e = str2double(app.ToleranceEditField.Value);

            % Get the selected method from the list box
            selectedMethod = app.MethodListBox.Value;

            % Call the corresponding solver function based on the selected method
            switch selectedMethod
                case 'Ole'
                    [x, y] = ole(@(x, y) eval(fxy), x(1), x(2), y0, N);
                case 'Hienantrungdiem'
                    [x, y] = hienantrungdiem(@(x, y) eval(fxy), x(1), x(2), y0, N);
                case 'Hienanhinhthang'
                    [x, y] = hienanhinhthang(@(x, y) eval(fxy), x(1), x(2), y0, N, e);
                case 'RK'
                    [x, y] = RK(@(x, y) eval(fxy), x(1), x(2), y0, N);
            end

            % Update the UIAxes with the plot
            plot(app.UIAxes, x, y);
            title(app.UIAxes, 'Đồ thị gần đúng y = f(x) ');
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'y');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.NhpPTviphnEditFieldLabel = uilabel(app.UIFigure);
            app.NhpPTviphnEditFieldLabel.Text = 'Nhập PT vi phân';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.EquationEditField.Value = '(1-x)*y';
            app.GiihnucuicamngxEditFieldLabel = uilabel(app.UIFigure);
            app.GiihnucuicamngxEditFieldLabel.Text = 'Giới hạn đầu cuối của mảng x';
            app.RangeEditField = uieditfield(app.UIFigure, 'text');
            app.RangeEditField.Value = '0 5';
            app.NhpsaiseLabel = uilabel(app.UIFigure);
            app.NhpsaiseLabel.Text = 'Nhập sai số e:';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'text');
            app.ToleranceEditField.Value = '0.001';
            app.Chn1trong4PPtnhgnngListBoxLabel = uilabel(app.UIFigure);
            app.Chn1trong4PPtnhgnngListBoxLabel.Text = 'Chọn 1 trong 4 PP tính gần đúng';
            app.MethodListBox = uilistbox(app.UIFigure);
            app.MethodListBox.Items = {'Ole', 'Hienantrungdiem', 'Hienanhinhthang', 'RK'};
            app.MethodListBox.Value = 'Ole';
            app.Nhpiukinuy0EditFieldLabel = uilabel(app.UIFigure);
            app.Nhpiukinuy0EditFieldLabel.Text = 'Nhập điều kiện đầu y0:';
            app.InitialConditionEditField = uieditfield(app.UIFigure, 'text');
            app.InitialConditionEditField.Value = '1';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.NhpsonconNEditFieldLabel = uilabel(app.UIFigure);
            app.NhpsonconNEditFieldLabel.Text = 'Nhập số đoạn con N:';
            app.SubintervalsEditField = uieditfield(app.UIFigure, 'text');
            app.SubintervalsEditField.Value = '25';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end